classdef Oscillator_GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        OscillatorTab                   matlab.ui.container.Tab
        SerialPanel_Oscillator          matlab.ui.container.Panel
        ConnectButton_Oscillator        matlab.ui.control.Button
        DisconnectResetButton_Oscillator  matlab.ui.control.Button
        ConnectedLampLabel              matlab.ui.control.Label
        ConnectedLamp_Oscillator        matlab.ui.control.Lamp
        SerialDevicesBox_Oscillator     matlab.ui.control.ListBox
        RefreshListButton_Oscillator    matlab.ui.control.Button
        FrequencyPanel                  matlab.ui.container.Panel
        FrequencySpinnerLabel           matlab.ui.control.Label
        FrequencySpinner                matlab.ui.control.Spinner
        SetButton                       matlab.ui.control.Button
        RequestedFreqLabel              matlab.ui.control.Label
        Label                           matlab.ui.control.Label
        MHzLabel                        matlab.ui.control.Label
        MHzLabel_2                      matlab.ui.control.Label
        ActualFreqLabel                 matlab.ui.control.Label
        LookbelowLabel                  matlab.ui.control.Label
        MHzLabel_3                      matlab.ui.control.Label
        PowerLabel                      matlab.ui.control.Label
        PowerSpinner                    matlab.ui.control.Spinner
        TextArea                        matlab.ui.control.TextArea
        ReadnextseriallineButton_Oscillator  matlab.ui.control.Button
        RecieverDSPTab                  matlab.ui.container.Tab
        plots                           matlab.ui.container.TabGroup
        PolarTab                        matlab.ui.container.Tab
        CartesianTab                    matlab.ui.container.Tab
        SerialPanel_Reciever            matlab.ui.container.Panel
        ConnectButton_Reciever          matlab.ui.control.Button
        DisconnectResetButton_Reciever  matlab.ui.control.Button
        ConnectedLamp_2Label            matlab.ui.control.Label
        ConnectedLamp_Reciever          matlab.ui.control.Lamp
        SerialDevicesBox_Reciever       matlab.ui.control.ListBox
        RefreshListButton_Reciever      matlab.ui.control.Button
        GetDataButton                   matlab.ui.control.Button
        StatusBox_Reciever              matlab.ui.control.TextArea
        RecieverRawTab                  matlab.ui.container.Tab
        SerialPanel_Reciever_2          matlab.ui.container.Panel
        ConnectButton_Reciever_2        matlab.ui.control.Button
        DisconnectResetButton_Reciever_2  matlab.ui.control.Button
        ConnectedLamp_2Label_2          matlab.ui.control.Label
        ConnectedLamp_Reciever_2        matlab.ui.control.Lamp
        SerialDevicesBox_Reciever_2     matlab.ui.control.ListBox
        RefreshListButton_Reciever_2    matlab.ui.control.Button
        GetDataButton_2                 matlab.ui.control.Button
        XZoomIn_RawReciever             matlab.ui.control.Button
        XZoomOut_RawReciever            matlab.ui.control.Button
        XZoomLabel                      matlab.ui.control.Label
        XZoomHome_RawReciever           matlab.ui.control.Button
        XShiftLeft_RawReciever          matlab.ui.control.Button
        XShiftRight_RawReciever         matlab.ui.control.Button
        XShiftLabel                     matlab.ui.control.Label
        XShiftHome_RawReciever          matlab.ui.control.Button
        ExportDataButton_RawReciever    matlab.ui.control.Button
        plots_2                         matlab.ui.container.TabGroup
        ADC1Tab                         matlab.ui.container.Tab
        UIAxes                          matlab.ui.control.UIAxes
        UIAxes2                         matlab.ui.control.UIAxes
        UIAxes3                         matlab.ui.control.UIAxes
        UIAxes4                         matlab.ui.control.UIAxes
        ADC2Tab                         matlab.ui.container.Tab
    end

    methods (Access = private)
        %add a check if still connected function
        function updateSerialViewer(app) %Oscillator serial text box
            global s
            text = "";
            while(s.BytesAvailable)
                text = text + fscanf(s);
                pause(0.1);
            end
            app.TextArea.Value = text;
        end
        
        function getData(app) % Get data from fpga
            global r
            global theta
            global SerialReciever   % FPGA Serial device instance
            
            recieved_array = 0;
            data_array = 0;
            try
                
                app.StatusBox_Reciever.Value = "Waiting for serial data to arrive";
                app.GetDataButton.Enable = 0;
                pause(0.1); %Otherwise we wont write to the textbox....
                
                % wait for "DATA" keyword
                while(isvalid(SerialReciever))
                    recieved = (fgetl(SerialReciever));
                    if recieved == "DATA"
                        break
                    end
                end
                
                % Read number of valeus
                app.StatusBox_Reciever.Value = "Processing data";
                recieved = str2num(fgetl(SerialReciever));
                number_of_samples = recieved;
                recieved = str2num(fgetl(SerialReciever)); %dump sample inbetween
                
                %Read the data
                for I = 1:(number_of_samples -1)
                    recieved = str2num(fgetl(SerialReciever));
                    data_array = [data_array, recieved];
                end
                
                app.StatusBox_Reciever.Value = "Plotting data";
                r= data_array;
                theta= 0:180/(number_of_samples-1):180;
                drawPolar(app);
                drawCartesian(app);
                
                app.StatusBox_Reciever.Value = "Complete";
                app.GetDataButton.Enable = 1;
                
                
            catch
                %Serial error
                app.StatusBox_Reciever.Value = "Error";
                msgbox("ERROR: serial is somehow not working (during getData function)");
                DisconnectResetButton_RecieverPushed(app);
            end
            
        end
        
        function drawPolar(app)
            global r
            global theta
            
            app.polarAxes = polaraxes(app.UIFigure);
            app.polarAxes.Units = 'normalized';
            app.polarAxes.Position = [0.1, 0.1, 0.8, 0.8];
            app.polarAxes.Parent = app.PolarTab;
            
            % Define theta and r
            theta_rad = (pi/180)*theta;
            
            % plot it
            polarplot(app.polarAxes, theta_rad, r);
        end
        
        function drawCartesian(app)
            global r
            global theta
            
            app.cartesianAxes = axes(app.UIFigure);
            app.cartesianAxes.Units = 'normalized';
            app.cartesianAxes.Position = [0.1, 0.1, 0.8, 0.8];
            app.cartesianAxes.Parent = app.CartesianTab;
            
            plot(app.cartesianAxes, theta,r);
            
        end
        
        function GetData_RawReciever(app)
            try
                app.GetDataButton_2.Enable = 0;
                global R
                global ch
                global XZOOMVALUE
                global XOFFSETVALUE
                
                recieved = '';
                flushinput(R);

                %wait for sampling
                f = waitbar(0,'Please press sample','WindowStyle','modal');
                while(1)
                    if(R.BytesAvailable) %Recieve only if available data
                        recieved = (fgetl(R));
                    end
                    if contains(recieved,'RAWDATA')
                        break
                    end
                    if(isvalid(R) == 0)
                        warndlg('ERROR: Serial not valid')
                        throw('Serial Error')
                    end
                    %pause(0.)
                end
                
                R.Timeout = 2; % so we don't wait as long on error
                
                %Read the data
                waitbar(0,f,'Reading in data')
                ch = zeros(4,8000);
                for i = 1:(8000 -1) %Recieve and split
                    if(isvalid(R) == 0)
                        %serial not valid
                        throw('Serial Error')
                    end
                    recieved = fgetl(R);
                    if contains(recieved,'RESET')
                        throw('Serial Error') %Not expecting to be reset while recieving
                    end
                    ch(4, i) = hex2dec(recieved(2:3));
                    ch(3, i) = hex2dec(recieved(4:5));
                    ch(2, i) = hex2dec(recieved(6:7));
                    ch(1, i) = hex2dec(recieved(8:9));
                    if (mod(i,800) == 0) %update wait bar
                        waitbar(i/8000,f)
                        pause(0.01)
                    end
                end
                
                %plot the data
                waitbar(0,f,'Plotting data')
                plot(app.UIAxes,ch(1, 1:7998))
                ylim(app.UIAxes,[0 256])
                waitbar(0.25,f)
                plot(app.UIAxes2,ch(2, 1:7998))
                ylim(app.UIAxes2,[0 256])
                waitbar(0.5,f)
                plot(app.UIAxes3,ch(3, 1:7998))
                ylim(app.UIAxes3,[0 256])
                waitbar(0.75,f)
                plot(app.UIAxes4,ch(4, 1:7998))
                ylim(app.UIAxes4,[0 256])
                
                XZOOMVALUE = 0;
                XOFFSETVALUE = 0;
                
                %finish up
                close(f)
                pause(0.01)
                app.GetDataButton_2.Enable = 1;
                
            catch
                %Serial error
                try
                    close(f)
                catch
                end
                app.StatusBox_Reciever.Value = "Error";
                msgbox("ERROR: Serial recieve error. Please reconnect");
                DisconnectResetButton_Reciever_2Pushed(app);
            end
%             
        end

        function results = DrawXAxis_RawReciever(app,ADC)
            global NumberOfSamples
            results = 1;
            
            %Check adc range
            if (ADC ~= 1 && ADC ~= 2)
                warndlg("ERROR: requested ADC view is not available")
                results = 0;
                return
            end
            
            %Zoom Check
            global XZOOMVALUE
            
            if XZOOMVALUE < 0
                warndlg("WARNING: Zoom limit reached")
                results = 0;
                return
            end   
            
            %Offset Check
            global XOFFSETVALUE;
            
            if XOFFSETVALUE < 0
                warndlg("WARNING: Offset limit reached")
                results = 0;
                return
            end
            
            XZoomAbsUpperLimit = NumberOfSamples/(2^XZOOMVALUE);
            if (XZoomAbsUpperLimit * XOFFSETVALUE) >= NumberOfSamples
                %Offset takes it past end of data
                XOFFSETVALUE = XOFFSETVALUE - 1;
                warndlg("WARNING: Offset limit reached")
                results = 0;
                return
            end
            
            %Calcs
            Offset = XZoomAbsUpperLimit * XOFFSETVALUE;
                
            %plots
            f = waitbar(0,'Drawing');
            if (ADC == 1)
                xlim(app.UIAxes,[Offset (XZoomAbsUpperLimit + Offset)])
                waitbar(0.25,f)
                xlim(app.UIAxes2,[Offset (XZoomAbsUpperLimit + Offset)])
                waitbar(0.5,f)
                xlim(app.UIAxes3,[Offset (XZoomAbsUpperLimit + Offset)])
                waitbar(0.75,f)
                xlim(app.UIAxes4,[Offset (XZoomAbsUpperLimit + Offset)])
                close(f)
            end
            if (ADC == 2)
                %Nothing here yet
            end

        end
    end
    
    properties (Access = private)
        polarAxes % Polar axes
        cartesianAxes
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            instrreset;
            
            global NumberOfSamples;
            
            app.SerialDevicesBox_Oscillator.Items = seriallist;
            app.SerialDevicesBox_Reciever.Items = seriallist;
            app.SerialDevicesBox_Reciever_2.Items = seriallist;
            NumberOfSamples = 8000;
        end

        % Button pushed function: ConnectButton_Oscillator
        function ConnectButton_OscillatorPushed(app, event)
            global s
            s = serial(app.SerialDevicesBox_Oscillator.Value);
            try
                fopen(s);
                app.TextArea.Value = string([s.baudrate," ", s.status]);
                if (s.status == "open")
                    app.ConnectedLamp_Oscillator.Color = [0 1 0];
                end
                app.ConnectButton_Oscillator.Enable = 0;
                app.SetButton.Enable = 1;
                app.FrequencySpinner.Enable = 1;
                app.SerialDevicesBox_Oscillator.Enable = 0;
                app.RefreshListButton_Oscillator.Enable = 0;
                app.PowerSpinner.Enable = 1;
            catch
                warndlg("ERROR: Cannot connect to port, is it in use?");
                app.SerialDevicesBox_Oscillator.Items = seriallist;
            end
        end

        % Button pushed function: DisconnectResetButton_Oscillator
        function DisconnectResetButton_OscillatorPushed(app, event)
            global s
            try
                fclose(s);
                if (s.status == "closed")
                    app.ConnectedLamp_Oscillator.Color = [1 0 0];
                end
                delete(s);
                clear s;
                app.ConnectButton_Oscillator.Enable = 1;
                app.SetButton.Enable = 0;
                app.FrequencySpinner.Enable = 0;
                app.SerialDevicesBox_Oscillator.Enable = 1;
                app.RefreshListButton_Oscillator.Enable = 1;
                app.PowerSpinner.Enable = 0;
            catch
            end
            app.SerialDevicesBox_Oscillator.Items = seriallist;
        end

        % Button pushed function: SetButton
        function SetButtonPushed(app, event)
            app.SetButton.Enable = 0;
            global s
            try
                fwrite(s, app.FrequencySpinner.Value*1000000,'ulong'); %First 4 bytes
                fwrite(s, app.PowerSpinner.Value,'uint8'); %1 byte
                app.Label.Text = string(app.FrequencySpinner.Value);
                pause(0.1);
                updateSerialViewer(app);
                app.SetButton.Enable = 1;
            catch
                DisconnectResetButton_OscillatorPushed(app);
            end
        end

        % Button pushed function: ReadnextseriallineButton_Oscillator
        function ReadnextseriallineButton_OscillatorPushed(app, event)
            updateSerialViewer(app);
        end

        % Button pushed function: RefreshListButton_Oscillator
        function RefreshListButton_OscillatorPushed(app, event)
            app.SerialDevicesBox_Oscillator.Items = seriallist;
        end

        % Button pushed function: DisconnectResetButton_Reciever
        function DisconnectResetButton_RecieverPushed(app, event)
            global SerialReciever
            global theta
            global r
            try
                app.StatusBox_Reciever.Value = "Reseting";
                
                app.GetDataButton.Enable = 0;
                
                %Clear the polar graph
                r= 1;
                theta= 0:1:180;
                drawPolar(app);
                
                %Cleanup serial
                fclose(SerialReciever);
                if (SerialReciever.status == "closed")
                    app.ConnectedLamp_Reciever.Color = [1 0 0];
                end
                delete(SerialReciever);
                clear SerialReciever;
                app.ConnectButton_Reciever.Enable = 1;
                app.SetButton.Enable = 0;
                app.FrequencySpinner.Enable = 0;
                app.PowerSpinner.Enable = 0;
                app.SerialDevicesBox_Reciever.Enable = 1;
                app.RefreshListButton_Reciever.Enable = 1;
                app.StatusBox_Reciever.Value = "Reset";
            catch
            end
            app.SerialDevicesBox_Reciever.Items = seriallist;
        end

        % Button pushed function: ConnectButton_Reciever
        function ConnectButton_RecieverPushed(app, event)
            global SerialReciever
            try
                SerialReciever = serial(app.SerialDevicesBox_Oscillator.Value);
                
                fopen(SerialReciever);
                if (SerialReciever.status == "open")
                    app.ConnectedLamp_Reciever.Color = [0 1 0];
                end
                %SerialReciever.Timeout = 5;
                app.ConnectButton_Reciever.Enable = 0;
                app.SerialDevicesBox_Reciever.Enable = 0;
                app.RefreshListButton_Reciever.Enable = 0;
                app.GetDataButton.Enable = 1;
            catch
                warndlg("ERROR: Cannot connect to port, is it in use?");
                app.SerialDevicesBox_Reciever.Items = seriallist;
            end
        end

        % Button pushed function: RefreshListButton_Reciever
        function RefreshListButton_RecieverPushed(app, event)
            app.SerialDevicesBox_Reciever.Items = seriallist;
        end

        % Button pushed function: GetDataButton
        function GetDataButtonPushed(app, event)
            getData(app);
        end

        % Button pushed function: ConnectButton_Reciever_2
        function ConnectButton_Reciever_2Pushed(app, event)
            global R
            try
                R = serial(app.SerialDevicesBox_Reciever_2.Value,'baud',115200);
                
                fopen(R);
                if (R.status == "open")
                    app.ConnectedLamp_Reciever_2.Color = [0 1 0];
                end
                app.ConnectButton_Reciever_2.Enable = 0;
                app.SerialDevicesBox_Reciever_2.Enable = 0;
                app.RefreshListButton_Reciever_2.Enable = 0;
                app.GetDataButton_2.Enable = 1;
            catch
                warndlg("ERROR: Cannot connect to port, is it in use?");
                app.SerialDevicesBox_Reciever_2.Items = seriallist;
            end
        end

        % Button pushed function: DisconnectResetButton_Reciever_2
        function DisconnectResetButton_Reciever_2Pushed(app, event)
            global R
            try
                fclose(R);
                if (R.status == "closed")
                    app.ConnectedLamp_Reciever_2.Color = [1 0 0];
                end
                delete(R);
                clear R;
                app.ConnectButton_Reciever_2.Enable = 1;
                app.SerialDevicesBox_Reciever_2.Enable = 1;
                app.RefreshListButton_Reciever_2.Enable = 1;
                app.GetDataButton_2.Enable = 0;
                app.ExportDataButton_RawReciever.Enable = 0;
            catch
            end
            app.SerialDevicesBox_Reciever_2.Items = seriallist;
        end

        % Button pushed function: RefreshListButton_Reciever_2
        function RefreshListButton_Reciever_2Pushed(app, event)
            app.SerialDevicesBox_Reciever_2.Items = seriallist;
        end

        % Button pushed function: GetDataButton_2
        function GetDataButton_2Pushed(app, event)
            GetData_RawReciever(app);
            app.ExportDataButton_RawReciever.Enable = 1;
        end

        % Button pushed function: XZoomIn_RawReciever
        function XZoomIn_RawRecieverButtonPushed(app, event)
            global XZOOMVALUE
            global XOFFSETVALUE
            XOFFSETVALUE = 0;
            XZOOMVALUE = XZOOMVALUE +1;
            DrawXAxis_RawReciever(app,1)
        end

        % Button pushed function: XZoomHome_RawReciever
        function XZoomHome_RawRecieverButtonPushed(app, event)
            global XZOOMVALUE
            global XOFFSETVALUE
            XOFFSETVALUE = 0;
            XZOOMVALUE = 0;
            DrawXAxis_RawReciever(app,1)
        end

        % Button pushed function: XZoomOut_RawReciever
        function XZoomOut_RawRecieverButtonPushed(app, event)
            global XZOOMVALUE
            global XOFFSETVALUE
            XOFFSETVALUE = 0;
            XZOOMVALUE = XZOOMVALUE -1;
            DrawXAxis_RawReciever(app,1)
        end

        % Button pushed function: XShiftHome_RawReciever
        function XShiftHome_RawRecieverButtonPushed(app, event)
            global XOFFSETVALUE
            XOFFSETVALUE = 0;
            DrawXAxis_RawReciever(app,1)
        end

        % Button pushed function: XShiftLeft_RawReciever
        function XShiftLeft_RawRecieverButtonPushed(app, event)
            global XOFFSETVALUE
            XOFFSETVALUE = XOFFSETVALUE -1;
            DrawXAxis_RawReciever(app,1)
        end

        % Button pushed function: XShiftRight_RawReciever
        function XShiftRight_RawRecieverButtonPushed(app, event)
            global XOFFSETVALUE
            XOFFSETVALUE = XOFFSETVALUE +1;
            DrawXAxis_RawReciever(app,1)
        end

        % Button pushed function: ExportDataButton_RawReciever
        function ExportDataButton_RawRecieverPushed(app, event)
            global ch
            %try
            [file, path] = uiputfile('export.csv');
            data = (ch - 127)/255;
            csvwrite(strcat(path,file),data.')
            %catch
             %   warndlg('ERROR: Unable to export')
            %end
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.OscillatorTab = uitab(app.TabGroup);
            app.OscillatorTab.Title = 'Oscillator';
            app.SerialPanel_Oscillator = uipanel(app.OscillatorTab);
            app.SerialPanel_Oscillator.Title = 'Serial';
            app.ConnectButton_Oscillator = uibutton(app.SerialPanel_Oscillator, 'push');
            app.ConnectButton_Oscillator.ButtonPushedFcn = createCallbackFcn(app, @ConnectButton_OscillatorPushed, true);
            app.ConnectButton_Oscillator.Text = 'Connect';
            app.DisconnectResetButton_Oscillator = uibutton(app.SerialPanel_Oscillator, 'push');
            app.DisconnectResetButton_Oscillator.ButtonPushedFcn = createCallbackFcn(app, @DisconnectResetButton_OscillatorPushed, true);
            app.DisconnectResetButton_Oscillator.Text = 'Disconnect/ Reset';
            app.ConnectedLampLabel = uilabel(app.SerialPanel_Oscillator);
            app.ConnectedLampLabel.Text = 'Connected';
            app.ConnectedLamp_Oscillator = uilamp(app.SerialPanel_Oscillator);
            app.SerialDevicesBox_Oscillator = uilistbox(app.SerialPanel_Oscillator);
            app.RefreshListButton_Oscillator = uibutton(app.SerialPanel_Oscillator, 'push');
            app.RefreshListButton_Oscillator.ButtonPushedFcn = createCallbackFcn(app, @RefreshListButton_OscillatorPushed, true);
            app.RefreshListButton_Oscillator.Text = 'Refresh List';
            app.FrequencyPanel = uipanel(app.OscillatorTab);
            app.FrequencyPanel.Title = 'Frequency';
            app.FrequencySpinnerLabel = uilabel(app.FrequencyPanel);
            app.FrequencySpinnerLabel.Text = 'Frequency';
            app.FrequencySpinner = uispinner(app.FrequencyPanel);
            app.FrequencySpinner.Limits = [35 4200];
            app.FrequencySpinner.ValueDisplayFormat = '%11.8g';
            app.FrequencySpinner.Enable = 'off';
            app.FrequencySpinner.Value = 35;
            app.SetButton = uibutton(app.FrequencyPanel, 'push');
            app.SetButton.ButtonPushedFcn = createCallbackFcn(app, @SetButtonPushed, true);
            app.SetButton.Enable = 'off';
            app.SetButton.Text = 'Set';
            app.RequestedFreqLabel = uilabel(app.FrequencyPanel);
            app.RequestedFreqLabel.Text = 'Requested Freq';
            app.Label = uilabel(app.FrequencyPanel);
            app.Label.Text = '';
            app.MHzLabel = uilabel(app.FrequencyPanel);
            app.MHzLabel.Text = 'MHz';
            app.MHzLabel_2 = uilabel(app.FrequencyPanel);
            app.MHzLabel_2.Text = 'MHz';
            app.ActualFreqLabel = uilabel(app.FrequencyPanel);
            app.ActualFreqLabel.Text = 'Actual Freq';
            app.LookbelowLabel = uilabel(app.FrequencyPanel);
            app.LookbelowLabel.Text = 'Look below';
            app.MHzLabel_3 = uilabel(app.FrequencyPanel);
            app.MHzLabel_3.Text = 'MHz';
            app.PowerLabel = uilabel(app.FrequencyPanel);
            app.PowerLabel.Text = 'Power';
            app.PowerSpinner = uispinner(app.FrequencyPanel);
            app.PowerSpinner.Limits = [0 3];
            app.PowerSpinner.ValueDisplayFormat = '%11.8g';
            app.PowerSpinner.Enable = 'off';
            app.PowerSpinner.Value = 3;
            app.TextArea = uitextarea(app.OscillatorTab);
            app.ReadnextseriallineButton_Oscillator = uibutton(app.OscillatorTab, 'push');
            app.ReadnextseriallineButton_Oscillator.ButtonPushedFcn = createCallbackFcn(app, @ReadnextseriallineButton_OscillatorPushed, true);
            app.ReadnextseriallineButton_Oscillator.Text = 'Read next serial line';
            app.RecieverDSPTab = uitab(app.TabGroup);
            app.RecieverDSPTab.Title = 'Reciever (DSP)';
            app.plots = uitabgroup(app.RecieverDSPTab);
            app.PolarTab = uitab(app.plots);
            app.PolarTab.Title = 'Polar';
            app.CartesianTab = uitab(app.plots);
            app.CartesianTab.Title = 'Cartesian';
            app.SerialPanel_Reciever = uipanel(app.RecieverDSPTab);
            app.SerialPanel_Reciever.Title = 'Serial';
            app.ConnectButton_Reciever = uibutton(app.SerialPanel_Reciever, 'push');
            app.ConnectButton_Reciever.ButtonPushedFcn = createCallbackFcn(app, @ConnectButton_RecieverPushed, true);
            app.ConnectButton_Reciever.Text = 'Connect';
            app.DisconnectResetButton_Reciever = uibutton(app.SerialPanel_Reciever, 'push');
            app.DisconnectResetButton_Reciever.ButtonPushedFcn = createCallbackFcn(app, @DisconnectResetButton_RecieverPushed, true);
            app.DisconnectResetButton_Reciever.Text = 'Disconnect/ Reset';
            app.ConnectedLamp_2Label = uilabel(app.SerialPanel_Reciever);
            app.ConnectedLamp_2Label.Text = 'Connected';
            app.ConnectedLamp_Reciever = uilamp(app.SerialPanel_Reciever);
            app.SerialDevicesBox_Reciever = uilistbox(app.SerialPanel_Reciever);
            app.RefreshListButton_Reciever = uibutton(app.SerialPanel_Reciever, 'push');
            app.RefreshListButton_Reciever.ButtonPushedFcn = createCallbackFcn(app, @RefreshListButton_RecieverPushed, true);
            app.RefreshListButton_Reciever.Text = 'Refresh List';
            app.GetDataButton = uibutton(app.SerialPanel_Reciever, 'push');
            app.GetDataButton.ButtonPushedFcn = createCallbackFcn(app, @GetDataButtonPushed, true);
            app.GetDataButton.Enable = 'off';
            app.GetDataButton.Text = 'Get Data';
            app.StatusBox_Reciever = uitextarea(app.RecieverDSPTab);
            app.StatusBox_Reciever.Value = {'Reciever Status'};
            app.RecieverRawTab = uitab(app.TabGroup);
            app.RecieverRawTab.Title = 'Reciever (Raw)';
            app.SerialPanel_Reciever_2 = uipanel(app.RecieverRawTab);
            app.SerialPanel_Reciever_2.Title = 'Serial';
            app.ConnectButton_Reciever_2 = uibutton(app.SerialPanel_Reciever_2, 'push');
            app.ConnectButton_Reciever_2.ButtonPushedFcn = createCallbackFcn(app, @ConnectButton_Reciever_2Pushed, true);
            app.ConnectButton_Reciever_2.Text = 'Connect';
            app.DisconnectResetButton_Reciever_2 = uibutton(app.SerialPanel_Reciever_2, 'push');
            app.DisconnectResetButton_Reciever_2.ButtonPushedFcn = createCallbackFcn(app, @DisconnectResetButton_Reciever_2Pushed, true);
            app.DisconnectResetButton_Reciever_2.Text = 'Disconnect/ Reset';
            app.ConnectedLamp_2Label_2 = uilabel(app.SerialPanel_Reciever_2);
            app.ConnectedLamp_2Label_2.Text = 'Connected';
            app.ConnectedLamp_Reciever_2 = uilamp(app.SerialPanel_Reciever_2);
            app.SerialDevicesBox_Reciever_2 = uilistbox(app.SerialPanel_Reciever_2);
            app.RefreshListButton_Reciever_2 = uibutton(app.SerialPanel_Reciever_2, 'push');
            app.RefreshListButton_Reciever_2.ButtonPushedFcn = createCallbackFcn(app, @RefreshListButton_Reciever_2Pushed, true);
            app.RefreshListButton_Reciever_2.Text = 'Refresh List';
            app.GetDataButton_2 = uibutton(app.SerialPanel_Reciever_2, 'push');
            app.GetDataButton_2.ButtonPushedFcn = createCallbackFcn(app, @GetDataButton_2Pushed, true);
            app.GetDataButton_2.Enable = 'off';
            app.GetDataButton_2.Text = 'Get Data';
            app.XZoomIn_RawReciever = uibutton(app.SerialPanel_Reciever_2, 'push');
            app.XZoomIn_RawReciever.ButtonPushedFcn = createCallbackFcn(app, @XZoomIn_RawRecieverButtonPushed, true);
            app.XZoomIn_RawReciever.Text = '+';
            app.XZoomOut_RawReciever = uibutton(app.SerialPanel_Reciever_2, 'push');
            app.XZoomOut_RawReciever.ButtonPushedFcn = createCallbackFcn(app, @XZoomOut_RawRecieverButtonPushed, true);
            app.XZoomOut_RawReciever.Text = '-';
            app.XZoomLabel = uilabel(app.SerialPanel_Reciever_2);
            app.XZoomLabel.Text = 'X Zoom:';
            app.XZoomHome_RawReciever = uibutton(app.SerialPanel_Reciever_2, 'push');
            app.XZoomHome_RawReciever.ButtonPushedFcn = createCallbackFcn(app, @XZoomHome_RawRecieverButtonPushed, true);
            app.XZoomHome_RawReciever.Text = {'='; ''};
            app.XShiftLeft_RawReciever = uibutton(app.SerialPanel_Reciever_2, 'push');
            app.XShiftLeft_RawReciever.ButtonPushedFcn = createCallbackFcn(app, @XShiftLeft_RawRecieverButtonPushed, true);
            app.XShiftLeft_RawReciever.Text = '<';
            app.XShiftRight_RawReciever = uibutton(app.SerialPanel_Reciever_2, 'push');
            app.XShiftRight_RawReciever.ButtonPushedFcn = createCallbackFcn(app, @XShiftRight_RawRecieverButtonPushed, true);
            app.XShiftRight_RawReciever.Text = '>';
            app.XShiftLabel = uilabel(app.SerialPanel_Reciever_2);
            app.XShiftLabel.Text = 'X Offset:';
            app.XShiftHome_RawReciever = uibutton(app.SerialPanel_Reciever_2, 'push');
            app.XShiftHome_RawReciever.ButtonPushedFcn = createCallbackFcn(app, @XShiftHome_RawRecieverButtonPushed, true);
            app.XShiftHome_RawReciever.Text = {'='; ''};
            app.ExportDataButton_RawReciever = uibutton(app.SerialPanel_Reciever_2, 'push');
            app.ExportDataButton_RawReciever.ButtonPushedFcn = createCallbackFcn(app, @ExportDataButton_RawRecieverPushed, true);
            app.ExportDataButton_RawReciever.Enable = 'off';
            app.ExportDataButton_RawReciever.Text = 'Export Data';
            app.plots_2 = uitabgroup(app.RecieverRawTab);
            app.ADC1Tab = uitab(app.plots_2);
            app.ADC1Tab.Title = 'ADC1';
            app.UIAxes = uiaxes(app.ADC1Tab);
            title(app.UIAxes, 'Channel 0')
            app.UIAxes.ZLim = [0 255];
            app.UIAxes2 = uiaxes(app.ADC1Tab);
            title(app.UIAxes2, 'Channel 2')
            app.UIAxes3 = uiaxes(app.ADC1Tab);
            title(app.UIAxes3, 'Channel 4')
            app.UIAxes4 = uiaxes(app.ADC1Tab);
            title(app.UIAxes4, 'Channel 6')
            app.ADC2Tab = uitab(app.plots_2);
            app.ADC2Tab.Title = 'ADC2';
        end
    end

    methods (Access = public)

        % Construct app
        function app = Oscillator_GUI

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end